classdef ControlStudy_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        SD_EF                           matlab.ui.control.NumericEditField
        SensorDelayLabel                matlab.ui.control.Label
        SO_EF                           matlab.ui.control.NumericEditField
        SensorOffsetLabel               matlab.ui.control.Label
        OD_EF                           matlab.ui.control.NumericEditField
        OffsetDisturbanceLabel          matlab.ui.control.Label
        LoC_EF                          matlab.ui.control.NumericEditField
        LimitsofcommandLabel            matlab.ui.control.Label
        SenN_EF                         matlab.ui.control.NumericEditField
        SensornoiseLabel                matlab.ui.control.Label
        SysN_EF                         matlab.ui.control.NumericEditField
        SystemnoiseLabel                matlab.ui.control.Label
        HTML                            matlab.ui.control.HTML
        ModelLabel                      matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        Pacing_SW                       matlab.ui.control.Switch
        OffNoFeedbackwhenOffLabel_2     matlab.ui.control.Label
        FinalvalueofPIDoutputEditField  matlab.ui.control.NumericEditField
        FinalvalueofPIDoutputEditFieldLabel  matlab.ui.control.Label
        FinalvalueoftheresponseEditField  matlab.ui.control.NumericEditField
        FinalvalueoftheresponseEditFieldLabel  matlab.ui.control.Label
        SimTime_EF                      matlab.ui.control.NumericEditField
        SimulationTimesLabel            matlab.ui.control.Label
        ResponseLabel                   matlab.ui.control.Label
        FB_SW                           matlab.ui.control.Switch
        OffNoFeedbackwhenOffLabel       matlab.ui.control.Label
        Dgain_EF                        matlab.ui.control.NumericEditField
        DgainLabel                      matlab.ui.control.Label
        Igain_EF                        matlab.ui.control.NumericEditField
        IgainLabel                      matlab.ui.control.Label
        Pgain_EF                        matlab.ui.control.NumericEditField
        PgainLabel                      matlab.ui.control.Label
        TS_EF                           matlab.ui.control.NumericEditField
        SamplingTimesLabel              matlab.ui.control.Label
        ModeltypeDropDown               matlab.ui.control.DropDown
        ModeltypeDropDownLabel          matlab.ui.control.Label
        Input_Fig                       matlab.ui.control.UIAxes
        Resp_Fig                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Copyright 2020 The MathWorks, Inc.

    properties (Access = private)
        model_name = 'ControlStudy_model';

        time_ref_sig_sim = 0;
        val_ref_sig_sim = 0;
        time_target_sig_sim = 0;
        val_target_sig_sim = 0;
        time_comm_sig_sim = 0;
        val_comm_sig_sim = 0;
        draw_time_step = 1;
        draw_time_line = 0;
        
        first_time_flag = true;
        running_flag = false;
        
        old_P_gain = 0;
        old_I_gain = 0;
        old_D_gain = 0;
        diff_limit = 1e-10;
    end

    methods (Access = private)

        function calc_simulation(app)
            % 初期化 / initialize
            param;
            mode = check_mode(app.ModeltypeDropDown.Value);
            sw_flag = check_FB_sw(app.FB_SW.Value);

            disable_all_editor(app);

            app.time_ref_sig_sim = 0;
            app.val_ref_sig_sim = 0;
            app.time_target_sig_sim = 0;
            app.val_target_sig_sim = 0;
            app.time_comm_sig_sim = 0;
            app.val_comm_sig_sim = 0;
            app.draw_time_line = app.draw_time_step;

            simin_data = Simulink.SimulationInput(app.model_name);
            % 外部からの入力をセット / set external input
            simin_data = simin_data.setExternalInput(app.create_externalInput());

            % シミュレーションペーシングを設定 / set Simulation Pacing
            if strcmp(app.Pacing_SW.Value, "On")
                simin_data = simin_data.setModelParameter('EnablePacing', 'on');
            else
                simin_data = simin_data.setModelParameter('EnablePacing', 'off');
            end
            simin_data = simin_data.setModelParameter('PacingRate', 1);

            % パラメータをセット / set parameters
            simin_data = simin_data.setVariable('TimeStep', app.TS_EF.Value);
            simin_data = simin_data.setVariable('SimEndTime', app.SimTime_EF.Value);
            simin_data = simin_data.setVariable('P_gain_model', app.Pgain_EF.Value);
            app.old_P_gain = app.Pgain_EF.Value;
            simin_data = simin_data.setVariable('I_gain_model', app.Igain_EF.Value);
            app.old_I_gain = app.Igain_EF.Value;
            simin_data = simin_data.setVariable('D_gain_model', app.Dgain_EF.Value);
            app.old_D_gain = app.Dgain_EF.Value;
            simin_data = simin_data.setVariable('Limits', app.LoC_EF.Value);
            simin_data = simin_data.setVariable('Off_dist', app.OD_EF.Value);
            simin_data = simin_data.setVariable('Sens_off', app.SO_EF.Value);
            simin_data = simin_data.setVariable('Sens_delay', app.SD_EF.Value);
            simin_data = simin_data.setVariable('System_noise', app.SysN_EF.Value);
            simin_data = simin_data.setVariable('Sensor_noise', app.SenN_EF.Value);
            simin_data = simin_data.setVariable('FB_off', ~sw_flag);

            simin_data = simin_data.setModelParameter('SimulationMode', 'Rapid');
            simin_data = simin_data.setModelParameter('RapidAcceleratorUpToDateCheck', 'off');

            % コールバックをセット / set callbacks
            % モデルトップ階層のOutportブロックの値が更新される度に呼び出されるコールバック
            simin_data = simulink.compiler.setExternalOutputsFcn(simin_data, ...
                @(idx, time, data) sim_processOutput(app, idx, time, data, mode));

            % モデルの1ステップの計算が終わる度に呼び出されるコールバック
            simin_data = simulink.compiler.setPostStepFcn(simin_data, ...
                @(time) sim_postStepFcn(app, time));

            % 実行 / run
            app.running_flag = true;
            simout = sim(simin_data);
            app.running_flag = false;

            % シミュレーション終了後にプロット / plot after simulation
            plot_graph(app);

            % 最終値をディスプレイに出力 / Show the final output values
            output_final_values(app, simout, mode);

            enable_all_editor(app);

            app.first_time_flag = false;
        end

        function exInput = create_externalInput(app)
            exInput = timeseries;
            exInput.Time = [0; 0.999; 1; 100];
            exInput.Data = [0; 0; 1; 1];
        end

        function sim_processOutput(app, idx, time, data, mode)
            switch mode
                case int32(1)
                    if (idx == 1)
                        app.time_ref_sig_sim = [app.time_ref_sig_sim; time];
                        app.val_ref_sig_sim = [app.val_ref_sig_sim; data];
                    elseif (idx == 2)
                        app.time_target_sig_sim = [app.time_target_sig_sim; time];
                        app.val_target_sig_sim = [app.val_target_sig_sim; data];
                    elseif (idx == 3)
                        app.time_comm_sig_sim = [app.time_comm_sig_sim; time];
                        app.val_comm_sig_sim = [app.val_comm_sig_sim; data];
                    end
                case int32(2)
                    if (idx == 4)
                        app.time_ref_sig_sim = [app.time_ref_sig_sim; time];
                        app.val_ref_sig_sim = [app.val_ref_sig_sim; data];
                    elseif (idx == 5)
                        app.time_target_sig_sim = [app.time_target_sig_sim; time];
                        app.val_target_sig_sim = [app.val_target_sig_sim; data];
                    elseif (idx == 6)
                        app.time_comm_sig_sim = [app.time_comm_sig_sim; time];
                        app.val_comm_sig_sim = [app.val_comm_sig_sim; data];
                    end
                case int32(3)
                    if (idx == 7)
                        app.time_ref_sig_sim = [app.time_ref_sig_sim; time];
                        app.val_ref_sig_sim = [app.val_ref_sig_sim; data];
                    elseif (idx == 8)
                        app.time_target_sig_sim = [app.time_target_sig_sim; time];
                        app.val_target_sig_sim = [app.val_target_sig_sim; data];
                    elseif (idx == 9)
                        app.time_comm_sig_sim = [app.time_comm_sig_sim; time];
                        app.val_comm_sig_sim = [app.val_comm_sig_sim; data];
                    end
                case int32(4)
                    if (idx == 10)
                        app.time_ref_sig_sim = [app.time_ref_sig_sim; time];
                        app.val_ref_sig_sim = [app.val_ref_sig_sim; data];
                    elseif (idx == 11)
                        app.time_target_sig_sim = [app.time_target_sig_sim; time];
                        app.val_target_sig_sim = [app.val_target_sig_sim; data];
                    elseif (idx == 12)
                        app.time_comm_sig_sim = [app.time_comm_sig_sim; time];
                        app.val_comm_sig_sim = [app.val_comm_sig_sim; data];
                    end
                otherwise

            end
        end

        function sim_postStepFcn(app, time)
            if (time >= app.draw_time_line && ~app.first_time_flag)
                % modifyParametersはシミュレーション終了時には実行できない
                if (time <= (app.SimTime_EF.Value - app.draw_time_step))
                    set_parameters_when_running(app);
                end

                plot_graph(app);

                drawnow;
                app.draw_time_line = app.draw_time_line + app.draw_time_step;
            end
        end

        function plot_graph(app)
            % 表示 / show the results
            hold(app.Resp_Fig, 'off');
            plot(app.Resp_Fig, app.time_ref_sig_sim, app.val_ref_sig_sim);
            hold(app.Resp_Fig, 'on');
            plot(app.Resp_Fig, app.time_target_sig_sim, app.val_target_sig_sim);
            hold(app.Resp_Fig, 'off');
            xlim(app.Resp_Fig, [0, app.SimTime_EF.Value]);
            plot(app.Input_Fig, app.time_comm_sig_sim, app.val_comm_sig_sim);
            xlim(app.Input_Fig, [0, app.SimTime_EF.Value]);

            legend(app.Resp_Fig, '指令値 / Command', '制御量 / Measured Outputs');
            legend(app.Input_Fig, '操作量 / Manipulated Variables');
        end

        function disable_all_editor(app)
            app.ModeltypeDropDown.Enable    = 'off';
            app.FB_SW.Enable                = 'off';
            app.Pacing_SW.Enable            = 'off';
            app.TS_EF.Enable                = 'off';
            app.SimTime_EF.Enable           = 'off';
            app.LoC_EF.Enable               = 'off';
            app.OD_EF.Enable                = 'off';
            app.SO_EF.Enable                = 'off';
            app.SD_EF.Enable                = 'off';
            app.SysN_EF.Enable              = 'off';
            app.SenN_EF.Enable              = 'off';
        end

        function enable_all_editor(app)
            app.ModeltypeDropDown.Enable    = 'on';
            app.FB_SW.Enable                = 'on';
            app.Pacing_SW.Enable            = 'on';
            app.TS_EF.Enable                = 'on';
            app.SimTime_EF.Enable           = 'on';
            app.LoC_EF.Enable               = 'on';
            app.OD_EF.Enable                = 'on';
            app.SO_EF.Enable                = 'on';
            app.SD_EF.Enable                = 'on';
            app.SysN_EF.Enable              = 'on';
            app.SenN_EF.Enable              = 'on';
        end

        function output_final_values(app, simout, mode)
            switch mode
                case int32(1)
                    app.FinalvalueoftheresponseEditField.Value = simout.yout{2}.Values.Data(end);
                    app.FinalvalueofPIDoutputEditField.Value =   simout.yout{3}.Values.Data(end);
                case int32(2)
                    app.FinalvalueoftheresponseEditField.Value = simout.yout{5}.Values.Data(end);
                    app.FinalvalueofPIDoutputEditField.Value =   simout.yout{6}.Values.Data(end);
                case int32(3)
                    app.FinalvalueoftheresponseEditField.Value = simout.yout{8}.Values.Data(end);
                    app.FinalvalueofPIDoutputEditField.Value =   simout.yout{9}.Values.Data(end);
                case int32(4)
                    app.FinalvalueoftheresponseEditField.Value = simout.yout{11}.Values.Data(end);
                    app.FinalvalueofPIDoutputEditField.Value =   simout.yout{12}.Values.Data(end);
                otherwise
            end
        end

        function set_parameters_when_running(app)

            if abs(app.old_P_gain - app.Pgain_EF.Value) > app.diff_limit
                simulink.compiler.modifyParameters(app.model_name, ...
                    Simulink.Simulation.Variable('P_gain_model', app.Pgain_EF.Value));
                app.old_P_gain = app.Pgain_EF.Value;
            end
            if abs(app.old_I_gain - app.Pgain_EF.Value) > app.diff_limit
                simulink.compiler.modifyParameters(app.model_name, ...
                    Simulink.Simulation.Variable('I_gain_model', app.Igain_EF.Value));
                app.old_I_gain = app.Igain_EF.Value;
            end
            if abs(app.old_D_gain - app.Dgain_EF.Value) > app.diff_limit
                simulink.compiler.modifyParameters(app.model_name, ...
                    Simulink.Simulation.Variable('D_gain_model', app.Dgain_EF.Value));
                app.old_D_gain = app.Dgain_EF.Value;
            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 外観の設定 / set appearance
            app.UIFigure.Name = 'PID制御のチューニング練習 / PID Tuning Boot Camp';
            % 最初の応答を計算 / first run
            calc_simulation(app);
        end

        % Value changed function: Pgain_EF
        function Pgain_EFValueChanged(app, event)
            % シミュレーションを再実行 / Rerun model
            if ~(app.running_flag)
                calc_simulation(app);
            end

        end

        % Value changed function: FB_SW
        function FB_SWValueChanged(app, event)
            % シミュレーションを再実行 / Rerun model
            calc_simulation(app);

        end

        % Value changed function: Igain_EF
        function Igain_EFValueChanged(app, event)
            % シミュレーションを再実行 / Rerun model
            if ~(app.running_flag)
                calc_simulation(app);
            end

        end

        % Value changed function: Dgain_EF
        function Dgain_EFValueChanged(app, event)
            % シミュレーションを再実行 / Rerun model
            if ~(app.running_flag)
                calc_simulation(app);
            end

        end

        % Value changed function: TS_EF
        function TS_EFValueChanged(app, event)
            % シミュレーションを再実行 / Rerun model
            calc_simulation(app);

        end

        % Value changed function: ModeltypeDropDown
        function ModeltypeDropDownValueChanged(app, event)
            % シミュレーションを再実行 / Rerun model
            calc_simulation(app);

        end

        % Value changed function: SimTime_EF
        function SimTime_EFValueChanged(app, event)
            % シミュレーションを再実行 / Rerun model
            calc_simulation(app);

        end

        % Value changed function: LoC_EF
        function LoC_EFValueChanged(app, event)
            % シミュレーションを再実行 / Rerun model
            calc_simulation(app);

        end

        % Value changed function: OD_EF
        function OD_EFValueChanged(app, event)
            % シミュレーションを再実行 / Rerun model
            calc_simulation(app);

        end

        % Value changed function: SO_EF
        function SO_EFValueChanged(app, event)
            % シミュレーションを再実行 / Rerun model
            calc_simulation(app);

        end

        % Value changed function: SD_EF
        function SD_EFValueChanged(app, event)
            % シミュレーションを再実行 / Rerun model
            calc_simulation(app);

        end

        % Value changed function: SysN_EF
        function SysN_EFValueChanged(app, event)
            % シミュレーションを再実行 / Rerun model
            calc_simulation(app);

        end

        % Value changed function: SenN_EF
        function SenN_EFValueChanged(app, event)
            % シミュレーションを再実行 / Rerun model
            calc_simulation(app);

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {704, 704};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {622, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ModelLabel = uilabel(app.LeftPanel);
            app.ModelLabel.Text = 'モデル / Model';
            app.HTML = uihtml(app.LeftPanel);
            app.HTML.HTMLSource = '.\ControlStudy_model\webview.html';
            app.SystemnoiseLabel = uilabel(app.LeftPanel);
            app.SystemnoiseLabel.Text = 'System noise';
            app.SysN_EF = uieditfield(app.LeftPanel, 'numeric');
            app.SysN_EF.Limits = [1e-06 Inf];
            app.SysN_EF.ValueChangedFcn = createCallbackFcn(app, @SysN_EFValueChanged, true);
            app.SysN_EF.Value = 0.2;
            app.SensornoiseLabel = uilabel(app.LeftPanel);
            app.SensornoiseLabel.Text = 'Sensor noise';
            app.SenN_EF = uieditfield(app.LeftPanel, 'numeric');
            app.SenN_EF.Limits = [1e-06 Inf];
            app.SenN_EF.ValueChangedFcn = createCallbackFcn(app, @SenN_EFValueChanged, true);
            app.SenN_EF.Value = 0.1;
            app.LimitsofcommandLabel = uilabel(app.LeftPanel);
            app.LimitsofcommandLabel.Text = 'Limits of command';
            app.LoC_EF = uieditfield(app.LeftPanel, 'numeric');
            app.LoC_EF.Limits = [0 Inf];
            app.LoC_EF.ValueChangedFcn = createCallbackFcn(app, @LoC_EFValueChanged, true);
            app.LoC_EF.Value = 2;
            app.OffsetDisturbanceLabel = uilabel(app.LeftPanel);
            app.OffsetDisturbanceLabel.Text = 'Offset Disturbance';
            app.OD_EF = uieditfield(app.LeftPanel, 'numeric');
            app.OD_EF.ValueChangedFcn = createCallbackFcn(app, @OD_EFValueChanged, true);
            app.OD_EF.Value = 1;
            app.SensorOffsetLabel = uilabel(app.LeftPanel);
            app.SensorOffsetLabel.Text = 'Sensor Offset';
            app.SO_EF = uieditfield(app.LeftPanel, 'numeric');
            app.SO_EF.ValueChangedFcn = createCallbackFcn(app, @SO_EFValueChanged, true);
            app.SensorDelayLabel = uilabel(app.LeftPanel);
            app.SensorDelayLabel.Text = 'Sensor Delay';
            app.SD_EF = uieditfield(app.LeftPanel, 'numeric');
            app.SD_EF.Limits = [0 100];
            app.SD_EF.ValueChangedFcn = createCallbackFcn(app, @SD_EFValueChanged, true);
            app.SD_EF.Value = 20;
            app.RightPanel = uipanel(app.GridLayout);
            app.Resp_Fig = uiaxes(app.RightPanel);
            title(app.Resp_Fig, 'システムの応答 / Response of system')
            xlabel(app.Resp_Fig, 'time[s]')
            ylabel(app.Resp_Fig, '-')
            app.Input_Fig = uiaxes(app.RightPanel);
            title(app.Input_Fig, 'PID制御器の出力 / Output of PID Controller')
            xlabel(app.Input_Fig, 'time[s]')
            ylabel(app.Input_Fig, '-')
            app.ModeltypeDropDownLabel = uilabel(app.RightPanel);
            app.ModeltypeDropDownLabel.Text = 'Model type:';
            app.ModeltypeDropDown = uidropdown(app.RightPanel);
            app.ModeltypeDropDown.Items = {'モデル0理想', 'モデル0現実', 'モデル1理想', 'モデル1現実'};
            app.ModeltypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeltypeDropDownValueChanged, true);
            app.ModeltypeDropDown.Value = 'モデル0理想';
            app.SamplingTimesLabel = uilabel(app.RightPanel);
            app.SamplingTimesLabel.Text = 'Sampling Time[s]';
            app.TS_EF = uieditfield(app.RightPanel, 'numeric');
            app.TS_EF.Limits = [0.001 0.1];
            app.TS_EF.ValueChangedFcn = createCallbackFcn(app, @TS_EFValueChanged, true);
            app.TS_EF.Value = 0.01;
            app.PgainLabel = uilabel(app.RightPanel);
            app.PgainLabel.Text = 'P gain';
            app.Pgain_EF = uieditfield(app.RightPanel, 'numeric');
            app.Pgain_EF.ValueChangedFcn = createCallbackFcn(app, @Pgain_EFValueChanged, true);
            app.IgainLabel = uilabel(app.RightPanel);
            app.IgainLabel.Text = 'I gain';
            app.Igain_EF = uieditfield(app.RightPanel, 'numeric');
            app.Igain_EF.ValueChangedFcn = createCallbackFcn(app, @Igain_EFValueChanged, true);
            app.DgainLabel = uilabel(app.RightPanel);
            app.DgainLabel.Text = 'D gain';
            app.Dgain_EF = uieditfield(app.RightPanel, 'numeric');
            app.Dgain_EF.ValueChangedFcn = createCallbackFcn(app, @Dgain_EFValueChanged, true);
            app.OffNoFeedbackwhenOffLabel = uilabel(app.RightPanel);
            app.OffNoFeedbackwhenOffLabel.Text = {'Offにするとフィードバックが'; '切断されます / No Feedback when Off'};
            app.FB_SW = uiswitch(app.RightPanel, 'slider');
            app.FB_SW.ValueChangedFcn = createCallbackFcn(app, @FB_SWValueChanged, true);
            app.FB_SW.Value = 'On';
            app.ResponseLabel = uilabel(app.RightPanel);
            app.ResponseLabel.Text = '応答 / Response';
            app.SimulationTimesLabel = uilabel(app.RightPanel);
            app.SimulationTimesLabel.Text = 'Simulation Time[s]';
            app.SimTime_EF = uieditfield(app.RightPanel, 'numeric');
            app.SimTime_EF.Limits = [5 100];
            app.SimTime_EF.ValueChangedFcn = createCallbackFcn(app, @SimTime_EFValueChanged, true);
            app.SimTime_EF.Value = 10;
            app.FinalvalueoftheresponseEditFieldLabel = uilabel(app.RightPanel);
            app.FinalvalueoftheresponseEditFieldLabel.Text = 'Final value of the response';
            app.FinalvalueoftheresponseEditField = uieditfield(app.RightPanel, 'numeric');
            app.FinalvalueofPIDoutputEditFieldLabel = uilabel(app.RightPanel);
            app.FinalvalueofPIDoutputEditFieldLabel.Text = 'Final value of PID output';
            app.FinalvalueofPIDoutputEditField = uieditfield(app.RightPanel, 'numeric');
            app.OffNoFeedbackwhenOffLabel_2 = uilabel(app.RightPanel);
            app.OffNoFeedbackwhenOffLabel_2.Text = 'ペーシング / Pacing';
            app.Pacing_SW = uiswitch(app.RightPanel, 'slider');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ControlStudy_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end